classdef CEM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        coastAxes                    matlab.ui.control.UIAxes
        SetConditionsPanel           matlab.ui.container.Panel
        waveangledegEditFieldLabel   matlab.ui.control.Label
        waveangleEditField           matlab.ui.control.NumericEditField
        InteractiveCheckBox          matlab.ui.control.CheckBox
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        waveheightmEditFieldLabel    matlab.ui.control.Label
        waveheightEditField          matlab.ui.control.NumericEditField
        CoastlineEvolutionLabel      matlab.ui.control.Label
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        fluxAxes                     matlab.ui.control.UIAxes
        waveAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        x                     % crosshore shoreline positions
        y                     % longshore shoreline positions
        alpha                 % wave angle relative to horizontal
        wave_height           % local wave height
        
        rho = 1020            % kg/m^3
        g = 9.8               % m^2/s
        
        stop                  % variable used to stop run
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stop = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
            % clear wave axes
            ax = app.waveAxes;
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal')
            
            % set shoreline axes
            ax = app.coastAxes;
            ylim(ax, [0 50]);
            xlim(ax, [0 150]);
            
            % set flux axes
            ax = app.fluxAxes;
            xlim(ax, [0 150]);
            
        end

        % Button pushed function: InitializeButton
        function Initialize(app, event)
            % initialize user input fields
            app.alpha = app.waveangleEditField.Value;
            app.wave_height = app.waveheightEditField.Value;
            cla(app.fluxAxes);
            
            % initilize coastline graph
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.y = (ones(1, 150)).*10;
                    
                case 'headland'
                    app.y = -10.*cos(linspace(0, 2*pi, 150)) + 10;
                    
                case 'bay'
                    app.y = 10.*cos(linspace(0, 2*pi, 150)) + 10;
                    
            end
            
            app.x = 1:1:150;            
            
            ax = app.coastAxes;
            plot(ax, app.x, app.y, 'b', 'linewidth', 2); ax.XLim = [0 150]; ax.YLim = [0 50];
            
            
            % add wave angle plot
            ax = app.waveAxes;
            cla(ax)
            hold(ax, 'on');
            for i = (0:2)
                x = [-cosd(app.alpha) 0 cosd(app.alpha)];
                y = [-sind(app.alpha) 0 sind(app.alpha)];
                if abs(app.alpha) > 45
                    x = x + i;
                else
                    y = y + i;
                end
                plot(ax, x, y, 'k--');
            end
            hold(ax, 'off')
            
            
            xticklabels(ax, []);
            xticks(ax, []);
            yticklabels(ax, []);
            yticks(ax, []);
            axis(ax, 'equal')
            
            % allow run
            app.RunButton.Enable = 'on';
            
            % initialize current run
            app.stop = 0;
        end

        % Button pushed function: RunButton
        function Run(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stop = 0;
            
            % set up axes
            hold(app.coastAxes, 'on');
            hold(app.fluxAxes, 'on');
            title(app.fluxAxes, 'flux out');
            
            vol_out = zeros(1, 150);
            vol_in = zeros(1, 150);
            vol_change = vol_in;
            
            % special case: leftmost index
            ax = app.fluxAxes;
            vol_out(1) = 1.1 * app.rho * app.g^(3/2) * app.wave_height^(5/2) * cosd(app.alpha) * sind(app.alpha);
            plot(ax, 2, vol_out(1), 'ro', 'markersize', 4, 'markerfacecolor', 'r'); 
            
            % start loop
            for i = (2:149)
                if app.stop
                    break
                end
                % plot wave and shoreline angle
                shore_angle = atan2d(app.y(i+1) - app.y(i-1), 2);
                ax = app.coastAxes;
                l1 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                    (3*sind(shore_angle)) + app.y(i)],...
                    'r', 'linewidth', 2);
                if app.alpha > 0
                    l2 = plot(ax, [i-3, i+3], [-(3*sind(shore_angle)) + app.y(i),...
                        (6*sind(app.alpha)) + app.y(i)],...
                    'r--', 'linewidth', 2);
                else
                    l2 = plot(ax, [i-3, i+3], [-(6*sind(app.alpha)) + app.y(i),...
                        (3*sind(shore_angle)) + app.y(i)],...
                    'r--', 'linewidth', 2);
                end
                p = plot(ax, i, app.y(i), 'r*', 'markersize', 10);
                
                % plot local sed transport
                if shore_angle > 90
                    shore_angle = 90 - shore_angle;
                end
                alpha = app.alpha - shore_angle;
                ax = app.fluxAxes;
                vol_out(i) = 1.1 * app.rho * app.g^(3/2) * app.wave_height^(5/2) * cosd(alpha) * sind(alpha);
                plot(ax, i, vol_out(i), 'ro', 'markersize', 4, 'markerfacecolor', 'r'); 
                
                % animate
                pause(.05)
                delete(l1); delete(l2); delete(p);
            end
            
            % special case: rightmost index
            ax = app.fluxAxes;
            vol_out(150) = 1.1 * app.rho * app.g^(3/2) * app.wave_height^(5/2) * cosd(app.alpha) * sind(app.alpha);
            plot(ax, 2, vol_out(150), 'ro', 'markersize', 4, 'markerfacecolor', 'r'); 
            
            % get volume in
            for i = (1:150)
                if vol_out(i) < 0 && i > 1
                    vol_in(i-1) = vol_out(i);
                elseif i < 150
                    vol_in(i+1) = vol_out(i);
                end
            end
            % get volume change
            vol_change = vol_in - vol_out;
            
            % plot flux in
            ax = app.fluxAxes;
            title(ax, 'flux in')
            plot(ax, app.x, vol_in(1:150), 'bo', 'markersize', 4, 'markerfacecolor', 'b');            
            pause(.5);
            
            % plot volume change
            title(ax, 'volume change')            
            plot(ax, app.x(2:149), 10.*vol_change(2:149), 'k--', 'linewidth', 2);
            
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';
            app.stop = 1;
            hold(app.coastAxes, 'off');
            hold(app.fluxAxes, 'off');
        end

        % Button pushed function: StopButton
        function Stop(app, event)
            % re-enable initialize button
            app.InitializeButton.Enable = 'on';
            % disable stop button
            app.StopButton.Enable = 'off';            
            % stop run
            app.stop = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.coastAxes = uiaxes(app.UIFigure);
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.waveangledegEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegEditFieldLabel.Text = 'wave angle (deg)';
            app.waveangleEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveangleEditField.Limits = [-90 90];
            app.waveangleEditField.RoundFractionalValues = 'on';
            app.waveangleEditField.Value = 45;
            app.InteractiveCheckBox = uicheckbox(app.SetConditionsPanel);
            app.InteractiveCheckBox.Text = 'Interactive';
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'headland';
            app.waveheightmEditFieldLabel = uilabel(app.SetConditionsPanel);
            app.waveheightmEditFieldLabel.Text = 'wave height (m)';
            app.waveheightEditField = uieditfield(app.SetConditionsPanel, 'numeric');
            app.waveheightEditField.Limits = [0 10];
            app.waveheightEditField.Value = 2;
            app.CoastlineEvolutionLabel = uilabel(app.UIFigure);
            app.CoastlineEvolutionLabel.Text = 'Coastline Evolution';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitializeButton.Text = 'Initialize';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @Stop, true);
            app.StopButton.Text = 'Stop';
            app.fluxAxes = uiaxes(app.UIFigure);
            app.waveAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = CEM

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end