classdef Refraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ControlsPanel                matlab.ui.container.Panel
        InitializeButton             matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        SetConditionsPanel           matlab.ui.container.Panel
        shorelineshapeDropDownLabel  matlab.ui.control.Label
        shorelineshapeDropDown       matlab.ui.control.DropDown
        waveperiodsecSpinnerLabel    matlab.ui.control.Label
        waveperiodSpinner            matlab.ui.control.Spinner
        contoursButtonGroup          matlab.ui.container.ButtonGroup
        parallelButton               matlab.ui.control.RadioButton
        shoalsButton                 matlab.ui.control.RadioButton
        borrowpitButton              matlab.ui.control.RadioButton
        waveangledegLabel            matlab.ui.control.Label
        waveangleSpinner             matlab.ui.control.Spinner
        WaveRefractionLabel          matlab.ui.control.Label
        SpeedKnobLabel               matlab.ui.control.Label
        SpeedKnob                    matlab.ui.control.Knob
        coastAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        wave_angle_deep                  % degrees
        T                                % wave period
        slope = 0.01;                   % shoreface slope
        
        x_pos                            % longshore wave positions
        y_pos                            % cross-shore wave positions
        
        x_step = .05;                      % longshore cell size (m)
        x = (0:.05:15);                   % longshore domain
        y_step = .05;                      % cross-shore cell size (m)
        y = (0:.05:10);                  % cross-shore domain
        h = zeros(201, 301)              % bathy grid
        grad                             % bathy gradient
        shoreline                        % y pos of shoreline
        c                                % vector of wave ray celerities
        wave_angles                      % vector of wave crest angles
        
        stopped                          % signifies app is stopped
        wait_time
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stopped = 1;
            app.RunButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
            % set up shoreline axes
            ax = app.coastAxes;
            colormap(ax, 'winter');
            cb = colorbar(ax, 'fontsize', 12);
            ylabel(cb, 'depth (m)', 'fontsize', 14); 
            ylim(ax, [0 app.y(end)]);
            xlim(ax, [0, app.x(end)]);
            ylabel(ax, 'cross-shore pos. (km)');   
            xlabel(ax, 'long-shore pos. (km)');
            axis(ax, 'equal');
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % init props
            app.x_pos = nan(1, 20); 
            app.y_pos = nan(1, 20);
            app.SpeedKnobValueChanged();
            
            % initialize user input
            app.wave_angle_deep = mod(app.waveangleSpinner.Value, 360);
            app.T = app.waveperiodSpinner.Value;
            
            % global values
            g = 9.81;
            c0 = ((g*app.T)/(2*pi))/1000; % deep water celerity in km
            app.c = c0*ones(size(app.x_pos)); % vector of wave ray celerities
            app.wave_angles = app.wave_angle_deep*ones(size(app.c));
            
            % initialize coastline
            switch app.shorelineshapeDropDown.Value
                case 'linear'
                    app.shoreline = (ones(1, length(app.x))).*10;
                    
                case 'headland'
                    shore_angles = linspace(0, 2*pi, length(app.x));
                    app.shoreline = -10.*cos(shore_angles) + 11;
                    
                case 'bay'
                    shore_angles = linspace(0, 2*pi, length(app.x));
                    app.shoreline = 10.*cos(shore_angles) + 11;                    
            end
            
            % build bathy grid
            for r = 1:length(app.y)
                for col = 1:length(app.x)
                    if r <= app.shoreline(col)
                        app.h(r, col) = 0;
                    else
                        app.h(r, col) = -1000 * (app.y_step * app.slope) * (r - app.shoreline(col));
                    end
                end
            end
            
            % add shoals
            if app.shoalsButton.Value
                radius = 1;    % radius of shoals (km)
                height = 0.01;    % max height of shoals (km)
                shoal_slope = height/radius;
                center_r = floor(length(app.y)/3);
                center_c = floor(length(app.x)/2);
                for r = (center_r - floor(radius/app.y_step):1:center_r + floor(radius/app.y_step))
                    for col = (center_c - floor(radius/app.x_step):1:center_c + floor(radius/app.x_step))
                        dist = sqrt(((center_r-r) * app.y_step)^2 + ((center_c-col) * app.x_step)^2);
                        if dist <= radius
                            elev = shoal_slope*(radius-dist);
                            app.h(r, col) = app.h(r, col) + 1000*elev;
                        end
                    end
                end
            end
            
            % add borrow pit
            if app.borrowpitButton.Value
                radius = 1;  % radius of borrow pit (km)
                depth = 0.01;   % max depth of borrow pit (km)
                pit_slope = depth/radius;
                center_r = floor(length(app.y)/3);
                center_c = floor(length(app.x)/2);
                for r = (center_r - floor(radius/app.y_step):1:center_r + floor(radius/app.y_step))
                    for col = (center_c - floor(radius/app.x_step):1:center_c + floor(radius/app.x_step))
                        dist = sqrt(((center_r-r) * app.y_step)^2 + ((center_c-col) * app.x_step)^2);
                        if dist <= radius
                            elev = pit_slope*(radius-dist);
                            app.h(r, col) = app.h(r, col) - 1000*elev;
                        end
                    end
                end
            end
            app.h(app.h == 0) = nan;
            
            % generate bathy iso-gradient angles
            [px, py] = gradient(app.h);
            app.grad = atan2d(py, px);
            app.grad = app.grad+90;
            app.grad = mod(app.grad, 360);
            
            % plot bathy
            ax = app.coastAxes;
            cla(ax);        
            set(ax, 'color', 'yellow');
            contourf(ax, app.x, app.y, app.h, 20);
            hold(ax, 'on');
            plot(ax, app.x, app.shoreline.*app.y_step, 'k-', 'linewidth', 2);
            
            % initialize wave vector
            crest_length = length(app.x)*app.x_step;
            crest_pos = linspace(0, crest_length, length(app.x_pos)); 
            xdists = crest_pos.*cosd(app.wave_angle_deep);
            ydists = crest_pos.*sind(app.wave_angle_deep);
            if sind(app.wave_angle_deep) < 0
                app.y_pos = app.y(end)+ydists;
                app.x_pos = app.x(end)-(xdists(end)-xdists);
            else
                app.y_pos = app.y(end)-(ydists(end)-ydists);
                app.x_pos = xdists;
            end
            % move wave crest to fit
            min_y = min(app.y_pos);
            if min_y < 6.5
                app.y_pos = app.y_pos + (6.5 - min_y);
                out_of_bounds = find (app.y_pos > app.y(end));
                app.x_pos(out_of_bounds) = [];
                app.y_pos(out_of_bounds) = [];
                app.c(out_of_bounds) = [];
                app.wave_angles(out_of_bounds) = [];
            end
            
            % plot wave crest
            plot(ax, app.x_pos, app.y_pos, 'd-k', 'markerfacecolor', 'k', 'markersize', 6, 'linewidth', 2);            
            
            % initialize run settings
            app.RunButton.Enable = 'on';
            app.stopped = 0;
        end

        % Callback function
        function RunButtonPushed(app, event)
            % disable initialize button while running
            app.InitializeButton.Enable = 'off';
            % disable run button while running
            app.RunButton.Enable = 'off';
            % enable stop button
            app.StopButton.Enable = 'on';
            app.stopped = 0;
            
            pi = 3.14159;
            magnitude = app.T/4;
            
            step = 1;
            while ~isempty(app.c)               
                if app.stopped
                    return
                end
                for i = (1:1:length(app.c))
                    cur_y = find(app.y <= app.y_pos(i), 1, 'last');
                    cur_x = find(app.x <= app.x_pos(i), 1, 'last');
                    % save values from previous step
                    last_angle = app.wave_angles(i)-app.grad(cur_y, cur_x);
                    last_c = app.c(i);
                    % get current depth
                    depth = -app.h(cur_y, cur_x);
                    if isempty(depth)
                        app.c(i) = nan;
                        continue
                    end
                    % get L with Newton-Raphson
                    [k, e] = newton_raphson(app.T, depth);
                    L = 2*pi/k;
                    % get c with dispersion equation
                    app.c(i) = (L/app.T)/1000; % celerity in km
                    % get theta with Snell's Law
                    arg = (app.c(i)/last_c)*sind(last_angle);
                    if abs(arg) > 1
                        arg = fix(arg);
                    end
                    app.wave_angles(i) = mod(asind(arg) + app.grad(cur_y, cur_x), 360);
                end
                
                % remove nans
                app.x_pos(isnan(app.c)) = [];
                app.y_pos(isnan(app.c)) = [];
                app.wave_angles(isnan(app.c)) = [];
                app.c(isnan(app.c)) = [];
                
                % update wave ray positions
                x_dist = (magnitude*app.c).*sind(app.wave_angles);
                y_dist = (magnitude*app.c).*cosd(app.wave_angles);
                x_new = app.x_pos + x_dist;    
                
                % merge rays which cross over
                cross_indices = find(x_new(1:end-1) > x_new(2:end));
                combined_x = x_dist(cross_indices+1) + x_dist(cross_indices);
                combined_y = y_dist(cross_indices+1) + y_dist(cross_indices);
                angles = atan2(combined_y, combined_x);
                app.wave_angles(cross_indices) = angles;
                app.wave_angles(cross_indices+1) = angles;
                
                % remove out of bounds rays
                remove_indices = find(x_new > app.x(end) | x_new < app.x(1));
                app.x_pos(remove_indices) = [];
                app.y_pos(remove_indices) = [];
                x_dist(remove_indices) = [];
                y_dist(remove_indices) = [];
                app.wave_angles(remove_indices) = [];
                app.c(remove_indices) = [];
                
                x_dist = (magnitude*app.c).*sind(app.wave_angles);
                y_dist = (magnitude*app.c).*cosd(app.wave_angles);
                x_new = app.x_pos + x_dist;
                y_new = app.y_pos - y_dist;
                
                ax = app.coastAxes;
                % plot wave rays
                for i = (1:1:length(app.x_pos))
                    plot(ax, [app.x_pos(i) x_new(i)], [app.y_pos(i) y_new(i)], 'o-r', 'markerfacecolor', 'r',...
                        'markersize', 2);
                end
                % plot new wave crest every minute
                app.x_pos = x_new;
                app.y_pos = y_new;
                if mod(step, 60/magnitude) < 1
                    plot(ax, app.x_pos, app.y_pos, 'o-k', 'markerfacecolor', 'r', 'markersize', 4);
                end
                step = step +1;
                pause(app.wait_time);
                
            end            
            
            % end procedure
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Value changed function: SpeedKnob
        function SpeedKnobValueChanged(app, event)
            app.wait_time = .01* (10/app.SpeedKnob.Value);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % re-enable initialize and start button
            app.InitializeButton.Enable = 'on';
            app.RunButton.Enable = 'on';
            % disable stop button 
            app.StopButton.Enable = 'off';            
            % stop run
            app.stopped = 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.shorelineshapeDropDownLabel = uilabel(app.SetConditionsPanel);
            app.shorelineshapeDropDownLabel.Text = 'shoreline shape';
            app.shorelineshapeDropDown = uidropdown(app.SetConditionsPanel);
            app.shorelineshapeDropDown.Items = {'linear', 'headland', 'bay'};
            app.shorelineshapeDropDown.Value = 'headland';
            app.waveperiodsecSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.waveperiodsecSpinnerLabel.Text = 'wave period (sec)';
            app.waveperiodSpinner = uispinner(app.SetConditionsPanel);
            app.waveperiodSpinner.Limits = [5 20];
            app.waveperiodSpinner.Value = 10;
            app.contoursButtonGroup = uibuttongroup(app.SetConditionsPanel);
            app.contoursButtonGroup.Title = 'contours';
            app.parallelButton = uiradiobutton(app.contoursButtonGroup);
            app.parallelButton.Text = 'parallel';
            app.parallelButton.Value = true;
            app.shoalsButton = uiradiobutton(app.contoursButtonGroup);
            app.shoalsButton.Text = 'shoals';
            app.borrowpitButton = uiradiobutton(app.contoursButtonGroup);
            app.borrowpitButton.Text = 'borrow pit';
            app.waveangledegLabel = uilabel(app.SetConditionsPanel);
            app.waveangledegLabel.Text = 'wave angle (deg)';
            app.waveangleSpinner = uispinner(app.SetConditionsPanel);
            app.waveangleSpinner.Step = 10;
            app.waveangleSpinner.Limits = [-90 90];
            app.waveangleSpinner.Value = 20;
            app.WaveRefractionLabel = uilabel(app.UIFigure);
            app.WaveRefractionLabel.Text = 'Wave Refraction';
            app.SpeedKnobLabel = uilabel(app.UIFigure);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedKnob.Limits = [1 10];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedKnobValueChanged, true);
            app.SpeedKnob.MinorTicks = [];
            app.SpeedKnob.Value = 2;
            app.coastAxes = uiaxes(app.UIFigure);
            xlabel(app.coastAxes, 'long-shore pos. (km)')
            ylabel(app.coastAxes, 'cross-shore pos. (km)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Refraction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end